classdef realTimeMessageRx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WindSpeedUIFigure           matlab.ui.Figure
        UITableRaw                  matlab.ui.control.Table
        Messaggio                   matlab.ui.control.TextArea
        MessageLabel                matlab.ui.control.Label
        StopReceiverButton_2        matlab.ui.control.Button
        StartReceiverButton         matlab.ui.control.Button
        MeasuredEvery2SecondsLabel  matlab.ui.control.Label
        ReceiverDataLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        receiverTimer % Timer object
        test % Description
        Pluto % Description
        preamble % Description
        sps % Description
        span % Description
        beta % Description
        seq_end % Description
        fc % Description
        SamplingRate % Description
    end

    properties (Access = public)
        seq_in = load('preamble.mat') % Description
    end

    methods (Access = private)

        function receiverFcn(app,src,event)
            % Get current time

            app.test = app.test+ 1;
            %app.Messaggio.Value = sprintf('aaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaa%.2f',app.test);
            RX_NO_GRAPH(app);
          
                
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.test = 0
       
            
            % Create timer object
            app.receiverTimer = timer(...
                "ExecutionMode","fixedRate", ...      % Run timer repeatedly
                "Period",3, ...                       % Period is 5 seconds
                "BusyMode","queue",...                % Queue timer callbacks when busy
                "TimerFcn",@app.receiverFcn);   % Callback that runs every period


            start(app.receiverTimer);


        end

        % Button pushed function: StartReceiverButton
        function StartReceiverButtonPushed(app, event)
             start(app.receiverTimer);
             TX_NO_GRAPH();
        end

        % Button pushed function: StopReceiverButton_2
        function StopReceiverButton_2Pushed(app, event)
            % Stop timer
            stop(app.receiverTimer);
            
        end

        % Close request function: WindSpeedUIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and figure
            stop(app.receiverTimer);
            delete(app.receiverTimer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WindSpeedUIFigure = uifigure('Visible', 'off');
            app.WindSpeedUIFigure.Name = 'Wind Speed';
            app.WindSpeedUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReceiverDataLabel = uilabel(app.WindSpeedUIFigure);
            app.ReceiverDataLabel.Text = 'Receiver Data';
            app.MeasuredEvery2SecondsLabel = uilabel(app.WindSpeedUIFigure);
            app.MeasuredEvery2SecondsLabel.Text = 'Measured Every 2 Seconds';
            app.StartReceiverButton = uibutton(app.WindSpeedUIFigure, 'push');
            app.StartReceiverButton.ButtonPushedFcn = createCallbackFcn(app, @StartReceiverButtonPushed, true);
            app.StartReceiverButton.Text = 'Start Receiver';
            app.StopReceiverButton_2 = uibutton(app.WindSpeedUIFigure, 'push');
            app.StopReceiverButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopReceiverButton_2Pushed, true);
            app.StopReceiverButton_2.Text = 'Stop Receiver';
            app.MessageLabel = uilabel(app.WindSpeedUIFigure);
            app.MessageLabel.Text = 'Message';
            app.Messaggio = uitextarea(app.WindSpeedUIFigure);
            app.Messaggio.Editable = 'off';
            app.UITableRaw = uitable(app.WindSpeedUIFigure);
            app.UITableRaw.ColumnName = {'Data'; 'Packet Number'; 'CRC OK'; 'END OK'};
            app.UITableRaw.RowName = {};
            % Show the figure after all components are created
            app.WindSpeedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimeMessageRx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WindSpeedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WindSpeedUIFigure)
        end
    end
end